classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        BLabel_2               matlab.ui.control.Label
        GLabel_2               matlab.ui.control.Label
        RLabel_2               matlab.ui.control.Label
        BLabel                 matlab.ui.control.Label
        GLabel                 matlab.ui.control.Label
        RLabel                 matlab.ui.control.Label
        OriginPictureLabel     matlab.ui.control.Label
        SaveAsButton           matlab.ui.control.Button
        Switch                 matlab.ui.control.Switch
        OKButton               matlab.ui.control.Button
        HighTripletLabel       matlab.ui.control.Label
        LowTripletLabel        matlab.ui.control.Label
        AdjustConstrastLabel   matlab.ui.control.Label
        Slider_6               matlab.ui.control.Slider
        Slider_5               matlab.ui.control.Slider
        Slider_4               matlab.ui.control.Slider
        Slider_3               matlab.ui.control.Slider
        Slider_2               matlab.ui.control.Slider
        Slider                 matlab.ui.control.Slider
        NoiseSlider            matlab.ui.control.Slider
        NoiseSliderLabel       matlab.ui.control.Label
        ChosePictureButton_2   matlab.ui.control.Button
        ImageEditField_2       matlab.ui.control.EditField
        ImageEditField_2Label  matlab.ui.control.Label
        PhotoEditorLabel_2     matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Image
        Modified
        TempEffect
        Noise
        Exit
        %contrast >>> https://www.mathworks.com/help/images/ref/imadjust.html
        LowR
        LowG
        LowB
        HighR
        HighG
        HighB
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            title(app.UIAxes,[]);
            xlabel(app.UIAxes,[]);
            ylabel(app.UIAxes,[]);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            %initialized parameters
            app.Exit = 0; %to activate each temporary effect permantily
            app.LowR=0.2;
            app.LowG=0.3;
            app.LowB=0;
            app.HighR=0.6;
            app.HighG=0.7;
            app.HighB=1;
        end

        % Button pushed function: ChosePictureButton_2
        function ChosePictureButton_2Pushed(app, event)
            [filename,pathname] = uigetfile({'*.jpg';'*.png';'*.*';'*.bmp';'*.gif';'*.tif'},'File Selector');
            app.ImageEditField_2.Value =strcat(pathname,filename);
            %Importing the selected image
            app.Image = imread(app.ImageEditField_2.Value);%it will be kept as an original copy
            %set initial modified image
            app.Modified = app.Image;
            %Display image and stretch it to fill the axes
            I = imshow(app.Modified,'Parent',app.UIAxes,...
                'XData',[1 app.UIAxes.Position(3)],...
                'YData',[1 app.UIAxes.Position(4)]);
            % set the axes limits
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Value changed function: NoiseSlider
        function NoiseSliderValueChanged(app, event)
            app.Noise = app.NoiseSlider.Value;
            app.TempEffect = app.Modified;
            if app.Noise > 0 % 0 is not accepted 
                app.TempEffect = imnoise(app.Modified,'gaussian',app.Noise);
                %Display image and stretch it to fill the axes
                I = imshow(app.TempEffect,'Parent',app.UIAxes,...
                    'XData',[1 app.UIAxes.Position(3)],...
                    'YData',[1 app.UIAxes.Position(4)]);
                % set the axes limits
                app.UIAxes.XLim = [0 I.XData(2)];
                app.UIAxes.YLim = [0 I.YData(2)];
            else
                app.TempEffect = app.Modified;
                %Display image and stretch it to fill the axes
                I = imshow(app.TempEffect,'Parent',app.UIAxes,...
                    'XData',[1 app.UIAxes.Position(3)],...
                    'YData',[1 app.UIAxes.Position(4)]);
                % set the axes limits
                app.UIAxes.XLim = [0 I.XData(2)];
                app.UIAxes.YLim = [0 I.YData(2)];
            end
            app.Exit = 1;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            if app.Exit == 1
                app.Modified = app.TempEffect;
                app.Exit = 0;% reset
            end
            value = app.Switch.Value;
            if strcmp(value,'On') == 1
                I = imshow(app.Image,'Parent',app.UIAxes,...
                    'XData',[1 app.UIAxes.Position(3)],...
                    'YData',[1 app.UIAxes.Position(4)]);
                % set the axes limits
                app.UIAxes.XLim = [0 I.XData(2)];
                app.UIAxes.YLim = [0 I.YData(2)];
            else
                I=imshow(app.Modified,'Parent',app.UIAxes,...
                    'XData',[1 app.UIAxes.Position(3)],...
                    'YData',[1 app.UIAxes.Position(4)]);
                % set the axes limits
                app.UIAxes.XLim = [0 I.XData(2)];
                app.UIAxes.YLim = [0 I.YData(2)];
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.LowR = app.Slider.Value;
            app.TempEffect = imadjust(app.Modified, [app.LowR app.LowG app.LowB; app.HighR app.HighG app.HighB], []);
            I = imshow(app.TempEffect,'Parent',app.UIAxes,...
                    'XData',[1 app.UIAxes.Position(3)],...
                    'YData',[1 app.UIAxes.Position(4)]);
                % set the axes limits
                app.UIAxes.XLim = [0 I.XData(2)];
                app.UIAxes.YLim = [0 I.YData(2)];
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            app.LowG = app.Slider_2.Value;
            app.TempEffect = imadjust(app.Modified,[app.LowR app.LowG app.LowB; app.HighR app.HighG app.HighB],[]);
            I = imshow(app.TempEffect,'Parent',app.UIAxes,...
                    'XData',[1 app.UIAxes.Position(3)],...
                    'YData',[1 app.UIAxes.Position(4)]);
                % set the axes limits
                app.UIAxes.XLim = [0 I.XData(2)];
                app.UIAxes.YLim = [0 I.YData(2)];  
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            app.LowB = app.Slider_3.Value;
            app.TempEffect = imadjust(app.Modified,[app.LowR app.LowG app.LowB; app.HighR app.HighG app.HighB],[]);
            I = imshow(app.TempEffect,'Parent',app.UIAxes,...
                    'XData',[1 app.UIAxes.Position(3)],...
                    'YData',[1 app.UIAxes.Position(4)]);
                % set the axes limits
                app.UIAxes.XLim = [0 I.XData(2)];
                app.UIAxes.YLim = [0 I.YData(2)];
        end

        % Value changed function: Slider_4
        function Slider_4ValueChanged(app, event)
            app.HighR = app.Slider_4.Value;
            app.TempEffect = imadjust(app.Modified,[app.LowR app.LowG app.LowB; app.HighR app.HighG app.HighB],[]);
            I = imshow(app.TempEffect,'Parent',app.UIAxes,...
                    'XData',[1 app.UIAxes.Position(3)],...
                    'YData',[1 app.UIAxes.Position(4)]);
                % set the axes limits
                app.UIAxes.XLim = [0 I.XData(2)];
                app.UIAxes.YLim = [0 I.YData(2)];
        end

        % Value changed function: Slider_5
        function Slider_5ValueChanged(app, event)
            app.HighG = app.Slider_5.Value;
            app.TempEffect = imadjust(app.Modified,[app.LowR app.LowG app.LowB; app.HighR app.HighG app.HighB],[]);
            I = imshow(app.TempEffect,'Parent',app.UIAxes,...
                    'XData',[1 app.UIAxes.Position(3)],...
                    'YData',[1 app.UIAxes.Position(4)]);
                % set the axes limits
                app.UIAxes.XLim = [0 I.XData(2)];
                app.UIAxes.YLim = [0 I.YData(2)];
        end

        % Value changed function: Slider_6
        function Slider_6ValueChanged(app, event)
            app.HighB = app.Slider_6.Value;
            app.TempEffect = imadjust(app.Modified,[app.LowR app.LowG app.LowB; app.HighR app.HighG app.HighB],[]);
            I = imshow(app.TempEffect,'Parent',app.UIAxes,...
                    'XData',[1 app.UIAxes.Position(3)],...
                    'YData',[1 app.UIAxes.Position(4)]);
                % set the axes limits
                app.UIAxes.XLim = [0 I.XData(2)];
                app.UIAxes.YLim = [0 I.YData(2)];
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.Modified = app.TempEffect;
            I = imshow(app.Modified,'Parent',app.UIAxes,...
                    'XData',[1 app.UIAxes.Position(3)],...
                    'YData',[1 app.UIAxes.Position(4)]);
                % set the axes limits
                app.UIAxes.XLim = [0 I.XData(2)];
                app.UIAxes.YLim = [0 I.YData(2)];
        end

        % Button pushed function: SaveAsButton
        function SaveAsButtonPushed(app, event)
            % https://www.mathworks.com/help/matlab/ref/uiputfile.html
            filter = {'*.jpg';'*.png';'*.jpeg';'*.bmp';'*.gif';'*.tif'};
            path = uiputfile(filter);
            imwrite(app.TempEffect,path);
            
        end

        % Callback function
        function ButtonPushed(app, event)
         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [0.1 0.34 0.61];
            app.UIAxes.YTick = [0.1 0.34 0.61];
            app.PhotoEditorLabel_2 = uilabel(app.UIFigure);
            app.PhotoEditorLabel_2.Text = 'Photo Editor';
            app.ImageEditField_2Label = uilabel(app.UIFigure);
            app.ImageEditField_2Label.Text = 'Image';
            app.ImageEditField_2 = uieditfield(app.UIFigure, 'text');
            app.ChosePictureButton_2 = uibutton(app.UIFigure, 'push');
            app.ChosePictureButton_2.ButtonPushedFcn = createCallbackFcn(app, @ChosePictureButton_2Pushed, true);
            app.ChosePictureButton_2.Text = 'Chose Picture';
            app.NoiseSliderLabel = uilabel(app.UIFigure);
            app.NoiseSliderLabel.Text = 'Noise';
            app.NoiseSlider = uislider(app.UIFigure);
            app.NoiseSlider.Limits = [0 1];
            app.NoiseSlider.ValueChangedFcn = createCallbackFcn(app, @NoiseSliderValueChanged, true);
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 0.5];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [0 0.5];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_3 = uislider(app.UIFigure);
            app.Slider_3.Limits = [0 0.5];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Slider_4 = uislider(app.UIFigure);
            app.Slider_4.Limits = [0.5 1];
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @Slider_4ValueChanged, true);
            app.Slider_4.Value = 0.5;
            app.Slider_5 = uislider(app.UIFigure);
            app.Slider_5.Limits = [0.5 1];
            app.Slider_5.ValueChangedFcn = createCallbackFcn(app, @Slider_5ValueChanged, true);
            app.Slider_5.Value = 0.5;
            app.Slider_6 = uislider(app.UIFigure);
            app.Slider_6.Limits = [0.5 1];
            app.Slider_6.ValueChangedFcn = createCallbackFcn(app, @Slider_6ValueChanged, true);
            app.Slider_6.Value = 0.5;
            app.AdjustConstrastLabel = uilabel(app.UIFigure);
            app.AdjustConstrastLabel.Text = 'Adjust Constrast';
            app.LowTripletLabel = uilabel(app.UIFigure);
            app.LowTripletLabel.Text = 'Low Triplet';
            app.HighTripletLabel = uilabel(app.UIFigure);
            app.HighTripletLabel.Text = 'High Triplet';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.SaveAsButton = uibutton(app.UIFigure, 'push');
            app.SaveAsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAsButtonPushed, true);
            app.SaveAsButton.Text = 'Save As';
            app.OriginPictureLabel = uilabel(app.UIFigure);
            app.OriginPictureLabel.Text = 'Origin Picture';
            app.RLabel = uilabel(app.UIFigure);
            app.RLabel.Text = 'R';
            app.GLabel = uilabel(app.UIFigure);
            app.GLabel.Text = 'G';
            app.BLabel = uilabel(app.UIFigure);
            app.BLabel.Text = 'B';
            app.RLabel_2 = uilabel(app.UIFigure);
            app.RLabel_2.Text = 'R';
            app.GLabel_2 = uilabel(app.UIFigure);
            app.GLabel_2.Text = 'G';
            app.BLabel_2 = uilabel(app.UIFigure);
            app.BLabel_2.Text = 'B';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end